classdef Hexapod_Generate_MultiSine_Excitation < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        RightPanel                      matlab.ui.container.Panel
        V072Label                       matlab.ui.control.Label
        RecommendationNote              matlab.ui.control.Label
        TimeforEachExperiment           matlab.ui.control.NumericEditField
        TimeforEachExperimentEditFieldLabel  matlab.ui.control.Label
        TotalTestingTime                matlab.ui.control.NumericEditField
        TotalTestingTimeEditFieldLabel  matlab.ui.control.Label
        ChooseFolderButton              matlab.ui.control.Button
        ChooseFolderEditField           matlab.ui.control.EditField
        ExportData                      matlab.ui.control.Button
        ExampleSignal                   matlab.ui.control.UIAxes
        fftAxes                         matlab.ui.control.UIAxes
        LeftPanel                       matlab.ui.container.Panel
        MaxAmplitudeSpinner             matlab.ui.control.Spinner
        MaxAmplitudeSpinnerLabel        matlab.ui.control.Label
        MaxAmplitudeSlider              matlab.ui.control.Slider
        MaxAmplitudeLabel               matlab.ui.control.Label
        IIHRHexapodMultisineGeneratorLabel  matlab.ui.control.Label
        ScaleFactorSpinner              matlab.ui.control.Spinner
        ScaleFactorSpinnerLabel         matlab.ui.control.Label
        NumberofRealiationsMSpinner     matlab.ui.control.Spinner
        NumberofRealiationsMSpinnerLabel  matlab.ui.control.Label
        NumberofPeriodsPSpinner         matlab.ui.control.Spinner
        NumberofPeriodsPSpinnerLabel    matlab.ui.control.Label
        MaximumFrequencyHzSpinner       matlab.ui.control.Spinner
        MaximumFrequencyHzSpinnerLabel  matlab.ui.control.Label
        GenerateMultisineButton         matlab.ui.control.Button
        MinimumFrequencyHzSpinner       matlab.ui.control.Spinner
        MinimumFrequencyLabel           matlab.ui.control.Label
        ExprimentalFrequencyBandHzSlider  matlab.ui.control.RangeSlider
        ExprimentalFrequencyBandHzSliderLabel  matlab.ui.control.Label
        Sampletime                      matlab.ui.control.NumericEditField
        SampletimeLabel                 matlab.ui.control.Label
        SamplingRateFsEditField         matlab.ui.control.NumericEditField
        SamplingRateFsEditFieldLabel    matlab.ui.control.Label
        NumberofInputsNuEditField       matlab.ui.control.NumericEditField
        NumberofInputsNuLabel           matlab.ui.control.Label
        SelectAllCheckBox               matlab.ui.control.CheckBox
        YawCheckBox                     matlab.ui.control.CheckBox
        PitchCheckBox                   matlab.ui.control.CheckBox
        RollCheckBox                    matlab.ui.control.CheckBox
        ZCheckBox                       matlab.ui.control.CheckBox
        YCheckBox                       matlab.ui.control.CheckBox
        XCheckBox                       matlab.ui.control.CheckBox
        progressBarAxes                 matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        nu = 0;                           % the number of inputs to simulate
        T_end = 0;                        % the number of seconds in one period of the experiment
        Fs_U = 256;                       % the sampling rate of the Hexapod in Hz
        Fs_Y = 2000;                      % the sampling rate of output measurements
        MaxFreq = 5;                      % the maximum frequency of the band of interest in Hz
        MinFreq = 0.25;                   % the minimum frequency of the band of interest in Hz
        minFreqRange = 0.1;               % the minimum frequency range of the band of interes in Hz
        Periods = 10;                     % the number of periods in one experiment
        Realizations = 2;                 % the number of realizations in one estimate of the FRF
        activeInputs = [];                % the active inputs for the multisine to populate
        data =  struct('dummyField',[]);  % struct where data is stored
        hex_path = struct('dt',[]);       % struct with hexapod inputs 
        scaleFactor = 1;                  % gain to increase the total number of samples by
        Dir = "";                         % where new data folders should be created
        isDir = false;                    % has the directory for data folders been specified
        MaxTranslation = 0.01;            % 1 cm max amplitude (or user defined)
        MaxRotation = pi*0.0111;          % 0.0349 rad (2 deg) max amplitude (or user defined)
        
        % basic optimoptions
        options = optimoptions('fmincon','OptimalityTolerance',1e-2);
    end
    
    methods (Access = private)
          
        function updateActiveCheckboxes(app)
            app.activeInputs = [app.XCheckBox.Value,app.YCheckBox.Value,...
                            app.ZCheckBox.Value,app.RollCheckBox.Value,...
                            app.PitchCheckBox.Value,app.YawCheckBox.Value];
            activeCount = sum(app.activeInputs);
            app.NumberofInputsNuEditField.Value = activeCount;
            app.nu = activeCount;
            calcExperimentTime(app);
        end
        
        % initialize the progress bar
        function initProgressBar(app)
            cla(app.progressBarAxes);
            barh(app.progressBarAxes, 0, 'FaceColor', [1 205/255 0/255],...
                'EdgeColor',[230/255 193/255 46/255]);
            app.progressBarAxes.YLim = [0.5 1.5];
            app.progressBarAxes.XLim = [0 1];
            app.progressBarAxes.YTick = [];
            app.progressBarAxes.XTick = [];
            app.progressBarAxes.XColor = 'black';
            app.progressBarAxes.YColor = 'black';
            title(app.progressBarAxes, 'Generating Multisine...','Color',[1 1 1]);
        end
        
        function updateProgressBar(app,progress)
            % Ensure progress is between 0 and 1
            progress = max(0,min(progress,1));
            barh(app.progressBarAxes, progress,'FaceColor', [1 205/255 0/255],...
                'EdgeColor',[230/255 193/255 46/255]);
            app.progressBarAxes.XColor = 'black';
            app.progressBarAxes.YColor = 'black';
            app.progressBarAxes.YLim = [0.5 1.5];
            app.progressBarAxes.XLim = [0 1];
            drawnow;
        end
        
        function plotFFT(app)
            Ts = 1/app.Fs_U;
            N = round((app.Fs_U/app.MinFreq))*app.scaleFactor; % one period
            freq = 1/Ts*(0:(N-1))/N; % frequency in Hz
            f = freq(1:round(N/2));
            %activeIndices = find(app.activeInputs);
            %legendInfo = cell(length(activeIndices),1);
            legendInfo = {};
            % we will just loop one experiment and realisation to not clutter the screen
            for k = 1:length(app.activeInputs)
                if app.activeInputs(k)
                    % compute FFT
                    Y = fft(app.data.u_out.M1.E1(k,app.Fs_U:N+app.Fs_U)); % exclude the ramp up
                    P2 = abs(Y./N);
                    P1 = P2(1:round(N/2));

                    % plot the FFT
                    %plot(app.fftAxes, freq, P2,'+');
                    plot(app.fftAxes, f, db(P1,"power"));
                    hold(app.fftAxes, 'on');

                    % id DOF name
                    if k == 1
                        dofName = 'X';
                    elseif k == 2
                        dofName = 'Y';
                    elseif k == 3
                        dofName = 'Z';
                    elseif k == 4
                        dofName = 'Roll';
                    elseif k == 5
                        dofName = 'Pitch';
                    else
                        dofName = 'Yaw';
                    end
                    % add to the legend
                   
                    legendInfo{end+1}= dofName;
                end
            end
            % add legend
            legend(app.fftAxes, legendInfo,"Location","bestoutside");
            xlim(app.fftAxes,[0, app.MaxFreq+1]);
            hold(app.fftAxes,'off');

        end

        function plotMultisine(app)
            % function to plot all periods of one experiments multisine
            Ts = 1/app.Fs_U;
            time = 0:Ts:(app.T_end*2+1);
            time = time(1:end-1);
            %how many N per period
            N = (app.Fs_U/app.MinFreq)*app.scaleFactor; 
            % find rows of app.data.u.E1.R1 that are not zero and plot
            % the first one. There will be at least one.
            dataIndex = find(any(app.data.u_out.M1.E1,2),1,"first");
            multisine = app.data.u_out.M1.E1(dataIndex,1:(2*N+app.Fs_U));
            plot(app.ExampleSignal,time,multisine);
            hold(app.ExampleSignal,"on");
            % put vert line at first period
            xline(app.ExampleSignal,time(N+app.Fs_U),'-r',{'1st','Period'}); 
            hold(app.ExampleSignal,"off");
        end
        
        function sampleTime(app)
            % calculate Sample time based on min frequency and sampling
            % rate
            N = round((app.Fs_U/app.MinFreq))*app.scaleFactor;
            app.T_end = N/app.Fs_U;
            app.Sampletime.Value = app.T_end;
            calcExperimentTime(app);
        end
        
        
        function calcExperimentTime(app)
            experiment_time = app.T_end*app.Periods+2;
            app.TimeforEachExperiment.Value = experiment_time;
            app.TotalTestingTime.Value = app.nu*(experiment_time)*app.Realizations;
        end
        
        function [leadIn, leadOut] = calcRampSignal(app,theSignal,Fs)
            % calculate lead in and lead out spline
            % p(t) = at^3+bt^2+ct+d
            % p'(t) = 3at^2+2bt+c
            % p(0) = d = 0
            % p(1) = a+b+c+d = theSignal(DOF,j,1)
            % p'(0) = c = 0
            % p'(1) = 3a+2b+c = Slope theSignal(DOF,j,1)
            XN = [theSignal(1),theSignal(end)];
            % compute gradient and solve at start and end points
            Xdot = gradient(theSignal,1/Fs);
            XNdot(1) = Xdot(1);
            XNdot(2) = Xdot(end);
            % system of Eqns lead in
            A1 = [0 0 0 1; 1 1 1 1; 0 0 1 0; 3 2 1 0];
            b1 = [0;XN(1);0;XNdot(1)];
            % system of Eqns lead out
            A2 = A1;
            b2 = [XN(2);0;XNdot(2);0];
            % solve coefficients
            coeffs1 = A1\b1;
            coeffs2 = A2\b2;
            % generate lead-in curve
            tSpline = linspace(0,1,Fs+1);
            leadIn = coeffs1(1)*tSpline.^3+coeffs1(2)*tSpline.^2+coeffs1(3)*tSpline+coeffs1(4);
            leadOut = coeffs2(1)*tSpline.^3+coeffs2(2)*tSpline.^2+coeffs2(3)*tSpline+coeffs2(4);
            app.data.info.N_lead_points = length(leadIn)-1;
            
        end
        
        function MultisineRate = leastCommonMultipleSamplingRate(app)
            lcm_ = lcm(app.Fs_U,app.Fs_Y);
            MultisineRate = lcm_;
        end

        
        function SetMaxAmplitude(app,MaxAmp)
            % set max translation
            fullMaxTranslation = 0.25; % 25 cm max translation
            app.MaxTranslation = MaxAmp/100 * fullMaxTranslation;
            % 1% = 2.5 mm
            % 2% = 5 mm
            % 4% = 10 mm

            % note absolute limits for translation [0.5, 2.5] cm
            
            % set max rotation
            fullMaxRotation = deg2rad(14.3239); % 22 deg max rotation, use 14.3 to match power spectrum translation
            app.MaxRotation = MaxAmp/100 * fullMaxRotation;
            % 1% = 0.14325 deg
            % 2% = 0.2865 deg
            % 4% = 0.573 deg

            % note absolute limits for rotation [0.44, 2.2] deg
        end
        
        % function r = Accel2Displace(app,a,Fs,N)
        %     % a function to transform a multisine Acceleration signal to
        %     % displacement
        %     omega = 2.*pi.*linspace(0,Fs,N);
        %     if app.nu == 1
        %         a_fft = fft(a);
        %         r_fft = (a_fft)./(-omega.^2);
        %         % we need to shift back and zero out the first element
        %         % in r_fft
        %         r_fft(1) = 1e-6;
        %         r = real(ifft(r_fft));
        %     else
        %         a_fft = fft(a,[],3);
        %         division_vector = reshape(-omega.^2,[1,1,N]);
        %         r_fft = a_fft./division_vector;
        %         % we need to shift back and zero out the first element
        %         % in r_fft
        %         sz = size(r_fft);
        %         r_fft(:,:,1) = 1e-6.*ones(sz(1),sz(2));
        %         % change back to time domain
        %         r = real(ifft(r_fft,[],3));  
        %     end
        % end


        function Cr_U = calcCrestFactor(app,r,Fs)
            % calculate Crest Factor
            u_rms = sqrt(mean(r.^2,2)); % operation is for each row
            P = (abs(fft(r,[],2)).^2)/length(r);
            f = Fs*(0:(length(r)/2))/length(r);
            f_band = [app.MinFreq, app.MaxFreq];
            band_indx = f>= f_band(1) & f <=f_band(2);
            P_tot = sum(P(:,1:length(r)/2+1),2); % total power in signal
            P_int = sum(P(:,band_indx),2); % power in band of interest
            Cr_U = max(abs(r),[],2)./(u_rms.*sqrt(P_int./P_tot));
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.MinimumFrequencyHzSpinner.Step = app.minFreqRange;
            app.MaximumFrequencyHzSpinner.Step = app.minFreqRange;
            % set default values
            % input DOF
            app.SelectAllCheckBox.Value = 1;
            SelectAllCheckBoxValueChanged(app);
            % sampling rate
            app.SamplingRateFsEditField.Value = 256;
            app.Fs_U = 256;
            % set hexapod_path dt var
            app.hex_path.dt = 1/(app.Fs_U);
            % scale factor for number of samples
            app.ScaleFactorSpinner.Value = 5;
            app.ScaleFactorSpinnerValueChanged(app);
            % Min and Max Frequency
            app.MinimumFrequencyHzSpinner.Value = 0.25;
            app.MinFreq = 0.25;
            app.MaximumFrequencyHzSpinner.Value = 5;
            app.MaxFreq = 5;
            % Number of Periods
            app.NumberofPeriodsPSpinner.Value = 10;
            app.NumberofPeriodsPSpinnerValueChanged(app);
            % Number of Realizations
            app.NumberofRealiationsMSpinner.Value = 2;
            app.NumberofRealiationsMSpinnerValueChanged(app);
            % Progress bar look
            app.progressBarAxes.XColor = 'black';
            app.progressBarAxes.YColor = 'black';
            % calculate sample time for one period
            sampleTime(app);
            % set maximum amplitude
            app.MaxAmplitudeSpinner.Value = 1;
            app.MaxAmplitudeSlider.Value = 1;
            SetMaxAmplitude(app,2);

            try
                parpool("Threads",[2 32]) % we'll let this stream to the console;
                useParallel = true;
            catch
                % is there a parallel pool already running??
                poolobj = gcp("nocreate");
                if isempty(poolobj)
                    uialert(app.UIFigure,"Warning parallel compute environment unavailable, multisine generation will be severely slowed",...
                        "Parallel Compute Warning","Icon","warning");
                    useParallel = false;
                else
                    poolsize = poolobj.NumWorkers
                    useParallel = true;
                end
               
            end
            
            app.options = optimoptions('fmincon','Algorithm','active-set',...
                'MaxIterations',100,'PlotFcn',@optimplotfval,'UseParallel',useParallel);

            % Added code to help fix bugs in the formatting on boot up
            % colors
            white = [1 1 1];
            black = [0 0 0];
            cool_gray = [0.39,0.40,0.42];
            gold = [1.00,0.80,0.00];
            % right panel
            app.RecommendationNote.FontColor = white;
            
            app.TimeforEachExperiment.FontColor = white;
            app.TimeforEachExperiment.BackgroundColor = cool_gray;
            app.TimeforEachExperimentEditFieldLabel.FontColor = white;
            
            app.TotalTestingTime.FontColor = white;
            app.TotalTestingTime.BackgroundColor = cool_gray;
            app.TotalTestingTimeEditFieldLabel.FontColor = white;
            
            app.ChooseFolderButton.FontColor = white;
            app.ChooseFolderButton.BackgroundColor = cool_gray;
            app.ChooseFolderEditField.FontColor = black;
            app.ChooseFolderEditField.BackgroundColor = gold;
            
            app.ExportData.FontColor = gold;
            app.ExportData.BackgroundColor = black;

            app.fftAxes.XColor = [0.85 0.85 0.85];
            app.fftAxes.YColor = [0.85 0.85 0.85];
            app.fftAxes.Title.Color = white;

            app.ExampleSignal.XColor = [0.85 0.85 0.85];
            app.ExampleSignal.YColor = [0.85 0.85 0.85];
            app.ExampleSignal.Title.Color = white;

            app.V072Label.FontColor = white;

            % left panel
            app.MaxAmplitudeSpinner.FontColor = black;
            app.MaxAmplitudeSpinner.BackgroundColor = gold;
            app.MaxAmplitudeSpinnerLabel.FontColor = white;
            
            app.MaxAmplitudeSlider.FontColor = white;
            app.MaxAmplitudeLabel.FontColor = white;
            
            app.IIHRHexapodMultisineGeneratorLabel.FontColor = white;
            app.ScaleFactorSpinner.FontColor = black;
            app.ScaleFactorSpinner.BackgroundColor = gold;
            app.ScaleFactorSpinnerLabel.FontColor = white;

            app.NumberofRealiationsMSpinner.FontColor = black;
            app.NumberofRealiationsMSpinner.BackgroundColor = gold;
            app.NumberofRealiationsMSpinnerLabel.FontColor = white;
            
            app.NumberofPeriodsPSpinner.FontColor = black;
            app.NumberofPeriodsPSpinner.BackgroundColor = gold;
            app.NumberofPeriodsPSpinnerLabel.FontColor = white;

            app.MaximumFrequencyHzSpinner.FontColor = black;
            app.MaximumFrequencyHzSpinner.BackgroundColor = gold;
            app.MaximumFrequencyHzSpinnerLabel.FontColor = white;

            app.GenerateMultisineButton.FontColor = black;
            app.GenerateMultisineButton.BackgroundColor = gold;
            
            app.MinimumFrequencyHzSpinner.FontColor = black;
            app.MinimumFrequencyHzSpinner.BackgroundColor = gold;
            app.MinimumFrequencyLabel.FontColor = white;
            
            app.ExprimentalFrequencyBandHzSlider.FontColor = white;
            app.ExprimentalFrequencyBandHzSliderLabel.FontColor = white;
            
            app.Sampletime.FontColor = white;
            app.Sampletime.BackgroundColor = cool_gray;
            app.SampletimeLabel.FontColor = white;
            
            app.SamplingRateFsEditField.FontColor = white;
            app.SamplingRateFsEditField.BackgroundColor = cool_gray;
            app.SamplingRateFsEditFieldLabel.FontColor = white;
            
            app.NumberofInputsNuEditField.FontColor = white;
            app.NumberofInputsNuEditField.BackgroundColor = cool_gray;
            app.NumberofInputsNuLabel.FontColor = white;
            
            app.SelectAllCheckBox.FontColor = [1 1 1];
            app.YawCheckBox.FontColor = [1 1 1];
            app.PitchCheckBox.FontColor = [1 1 1];
            app.RollCheckBox.FontColor = [1 1 1];
            app.ZCheckBox.FontColor = [1 1 1];
            app.YCheckBox.FontColor = [1 1 1];
            app.XCheckBox.FontColor = [1 1 1];

        end

        % Button pushed function: GenerateMultisineButton
        function GenerateMultisineButtonPushed(app, event)
            % init progress bar
            initProgressBar(app);
            % clear any written variables of data
            if isfield(app.data,'r') || isfield(app.data,'u_out') || isfield(app.data,'u_measure')
                app.data = rmfield(app.data,{'u_out','u_measure','r'});
            end
            if isfield(app.data,'info')
                if isfield(app.data.info,'Cr')
                    app.data.info = rmfield(app.data.info,'Cr');
                end
            end
            % Validate that at least one DOF is selected
            if app.nu == 0
                uialert(app.UIFigure,'Please select at least one input.',...
                    'Input Error');
                return
            end
            % Sampling rate for multisine
            multisineRate = leastCommonMultipleSamplingRate(app);
            % Inputs are valid, calculate Multisine
            %N = round((multisineRate/app.MinFreq))*app.scaleFactor;
            %app.MinFreq = (multisineRate/N)*app.scaleFactor;
            %ExcitedHarm = round((app.MinFreq*N)/multisineRate:1:(2*app.MaxFreq*N)/multisineRate); % MinFreq - MaxFreq (hz)
            DefFreq.fs = multisineRate;
            DefFreq.fmin = app.MinFreq;
            DefFreq.fmax = 2*app.MaxFreq;
            %DefFreq.fmax = app.MaxFreq;
            DefFreq.fres = app.MinFreq/app.scaleFactor; % apply SF to lengthen T_0 period
            Nblock = Inf; % no NL detection
            Spacing = 'linear'; % linearly spaced frequency grid
            TypeMulti = 'full';
            [ExcitedHarm, N, NewDefFreq] = HarmMultisine(DefFreq, Nblock, Spacing, TypeMulti);
            ExcitedHarm = ExcitedHarm';
            nh = length(ExcitedHarm);
            % actual DFT frequencies used
            freqResolution = NewDefFreq.fres;
            app.data.Frequencies = NewDefFreq.fmin:freqResolution:NewDefFreq.fmax/2;
            % write the real frequencies to the spinboxes
            app.MinimumFrequencyHzSpinner.Value = app.data.Frequencies(1);
            app.MinFreq = app.data.Frequencies(1);
            app.MaximumFrequencyHzSpinner.Value = app.data.Frequencies(end);
            app.MaxFreq = app.data.Frequencies(end);

            % define amplitude auto power spectrum for inputs
            AmplitudeExcitedHarm = ones(app.nu, nh);
            AmplitudeExcitedHarm(:, floor(nh/2):end) = 1e-6; % relative amplitudes (.*scalerVector.*ones(app.nu,floor(nh/2+1)))
            if app.nu == 1
                % special case, use SISO multisine generator
                for i = 1:app.Realizations
                    p = 32; % diminishing returns after P = 32 per paper
                    T0 = app.T_end;
                    initial_phases = 2*pi*rand(size(ExcitedHarm));
                    
                    % let's modify the options to get as close to sqrt(2)
                    % as we can
                    app.options.FunctionTolerance = 5e-3;
                    app.options.StepTolerance = 1e-4; % if the step size is too small stop
                    app.options.Display = 'iter';

                    % l2p norm to minimize crest factor
                    % optimize phases to min crest factor for displacement
                    % and acceleration
                    objectiveFunc = @(Phases) calcL2pMultisine(Phases,ExcitedHarm,N,AmplitudeExcitedHarm,p,T0,multisineRate);
                    
                    optimalPhases = fmincon(objectiveFunc,initial_phases,[],[],[],[],zeros(size(initial_phases)),...
                        2*pi*ones(size(initial_phases)),[],app.options);

                    r = CalcMultisinel2p(ExcitedHarm,N,AmplitudeExcitedHarm,optimalPhases);

                    r = r';

                    % for debugging
                    %omega = 2.*pi.*linspace(0,multisineRate,N);
                    %r_fft = abs(fft(r));
                    %a_fft = (r_fft)./(-omega.^2);

                    % replicate the signal across the periods
                    theSignal = repmat(r,[1,app.Periods]);
                    
                    % update the progress bar
                    updateProgressBar(app, i / app.Realizations);

                    % init our 6 x 1 x N*P+2*Fs input signal and reference
                    theExcitation = zeros(6,length(theSignal)+2*(multisineRate));
                    theReference = zeros(1,N);
                    % % Define maximum amplitudes
                    % % identify which DOF and package the signal
                    activeDOFIndices = find(app.activeInputs);
                    scalerVector = ones(app.nu,1);
                    % identify which DOF and package the signal
                    for DOF = 1:length(activeDOFIndices)
                        dofIndex = activeDOFIndices(DOF);
                        % calc clamp before lead in and out. Otherwise we
                        % get some nolinear affects on our design
                        if dofIndex == 1 || dofIndex == 2 || dofIndex == 3
                            scaler = app.MaxTranslation/max(abs(theSignal));
                        else
                            scaler = app.MaxRotation/max(abs(theSignal));
                        end
                        scalerVector(DOF) = scaler;
                        
                        % add 1 second lead in and lead out to the signal 
                        % for smooth operation.
                        [leadIn,leadOut] = calcRampSignal(app,theSignal,multisineRate);
                        % package and remove duplicates in leadIn and
                        % leadOut
                        temp = [leadIn(1:end-1), theSignal, leadOut(2:end)];
                        % clamp large amplitudes
                        
                        scaled_signal = scalerVector(DOF).*temp;
                        scaled_r = scalerVector(DOF).*r;

                        % package
                        theExcitation(dofIndex,:) = scaled_signal;
                        theReference(DOF,:) = scaled_r; % clean reference signal
                    end % DOF loop

                    % package theExcitation
                    theRealization = strcat('M',string(i));
                    % resample out to 256 Hz
                    indx_out = 1:multisineRate/app.Fs_U:length(theExcitation);
                    u_out = theExcitation(:,indx_out);
                    app.data.u_out.(theRealization).E1 = u_out;
                    % resample reference and measurement to 2000 Hz
                    indx_ref = 1:multisineRate/app.Fs_Y:length(theReference);
                    r_ = theReference(1,indx_ref);
                    app.data.r.(theRealization).E1 = r_;
                    % for debugging
                    %omega2 = 2.*pi.*linspace(0,app.Fs_Y,length(r_));
                    %fft_r_ = abs(fft(r_));
                    % calculate the crest factor and add it to info
                    Cr = calcCrestFactor(app,r_,app.Fs_Y)
                    app.data.info.Cr(1,theRealization) = array2table(Cr);
                    indx_meas = 1:multisineRate/app.Fs_Y:length(theExcitation);
                    u_measure = theExcitation(dofIndex,indx_meas);
                    app.data.u_measure.(theRealization).E1 = u_measure;
                end % i loop

                plotFFT(app);
                plotMultisine(app);
                updateProgressBar(app, 1);
                title(app.progressBarAxes,'Finished','Color',[1 1 1]);
            else
                % general case
                nsteps = app.Realizations * app.nu;
                count = 0;
                for i = 1:app.Realizations
                    p = 32; % diminishing returns after P = 32
                    T0 = app.T_end;
                    intialPhaseMatrix = zeros(app.nu,2,nh);
                    intialPhaseMatrix(:,1,:) = 2*pi*rand(app.nu,nh);
                    intialPhaseMatrix(:,2,:) = 2*pi*rand(app.nu,nh);

                    optimalPhaseMatrix = zeros(app.nu,2,nh);
                    optimalPhaseMatrix(:,2,:) = intialPhaseMatrix(:,2,:);

                    initial_phase_vector = reshape(intialPhaseMatrix(:,1,:),[],1);

                    % let's modify the options to get as close to sqrt(2)
                    % as we can
                    app.options.FunctionTolerance = 5e-2*app.nu; % try to get each DOF & experiment within 0.1
                    app.options.StepTolerance = 1e-4*app.nu; % if the step size is too small stop
                    app.options.MaxIterations = 30; % limit total number of optimization steps
                    app.options.Display = 'iter';
                    
                    % l2p norm to minimize crest factor
                    % optimize phases to min crest factor for displacement
                    % and acceleration
                    objectiveFunc = @(Phases) calcL2pMIMO(Phases,intialPhaseMatrix,ExcitedHarm,N,AmplitudeExcitedHarm,p,T0,multisineRate);
                    
                    optimalPhases = fmincon(objectiveFunc,initial_phase_vector,[],[],[],[],zeros(size(initial_phase_vector)),...
                        2*pi*ones(size(initial_phase_vector)),[],app.options);

                    optimalPhaseMatrix(:,1,:) = reshape(optimalPhases,[app.nu,1,nh]);
                    

                    r = Calc_MIMO_Multisine_L2p(ExcitedHarm,N,AmplitudeExcitedHarm,optimalPhaseMatrix);

                    %r = Calc_MIMO_Multisine(ExcitedHarm',N,AmplitudeExcitedHarm);
                    
                    %omega = 2.*pi.*linspace(0,multisineRate,N);    
                    % recompute a
                    % r_fft = fft(r(:,j,:));
                    % a_fft = squeeze(r_fft).*(-omega.^2);
                    % a(:,j,:) = reshape(real(ifft(a_fft)),app.nu,1,[]);
                    
                    % replicate r across the number of periods
                    theSignal = repmat(r,[1,1,app.Periods]);

                    % init our 6 x 1 x N*P+2*Fs input signal and reference
                    theExcitation = zeros(6,app.nu,length(theSignal)+2*(multisineRate));
                    theReference = zeros(app.nu,app.nu,N);

                    activeDOFIndices = find(app.activeInputs);
                    % loop over each experiment
                    for j = 1:app.nu
                        theRealization = strcat('M',string(i));
                        theExperiment = strcat('E',string(j));
                        % loop over each active DOF
                        for DOF = 1:length(activeDOFIndices)
                            dofIndex = activeDOFIndices(DOF);
                            % Define maximum amplitudes
                            
                            if dofIndex == 1 || dofIndex == 2 || dofIndex == 3
                                scaler = app.MaxTranslation/max(abs(r(DOF,j,:)));
                            else
                                scaler = app.MaxRotation/max(abs(r(DOF,j,:)));
                            end
                            
                            % clamp large amplitudes
                            r(DOF,j,:) = scaler.*r(DOF,j,:);
                            theSignal(DOF,j,:) = scaler.*theSignal(DOF,j,:);

                            % calculate lead in and lead out spline
                            tempSignal = reshape(theSignal(DOF,j,:),1,length(theSignal(DOF,j,:)));
                            [leadIn,leadOut] = calcRampSignal(app,tempSignal,multisineRate);
                            % package and remove duplicates in leadIn and
                            % leadOut
                            temp(1,1,:) = [leadIn(1:end-1)'; squeeze(theSignal(DOF,j,:)); leadOut(2:end)'];
                            % package
                            theExcitation(dofIndex,j,:) = temp;
                            theReference(DOF,j,:) = r(DOF,j,:); % clean reference signal
                        end % DOF loop

                        % write all DOFS to the data struct and down sample
                        % to 256 Hz
                        indx_out = (1:multisineRate/app.Fs_U:length(theExcitation));    
                        app.data.u_out.(theRealization).(theExperiment) = squeeze(theExcitation(:,j,indx_out));
                        % resample reference and measurement to 2000 Hz
                        indx_ref = (1:multisineRate/app.Fs_Y:length(theReference));
                        r_ = squeeze(theReference(:,j,indx_ref));
                        app.data.r.(theRealization).(theExperiment) = r_;
                        % for debugging
                        %omega2 = 2.*pi.*linspace(0,app.Fs_Y,length(r_));
                        %fft_r_ = abs(fft(r_'));
                        % Calculate the crest factor and add it to info
                        Cr = calcCrestFactor(app,r_,app.Fs_Y) % we get a nux1 row vector of Cr for each DOF
                        try
                            app.data.info.Cr.(theRealization)(1:app.nu,theExperiment) = array2table(Cr);
                        catch e
                            fprintf("Encountered error writing to table %s",e);
                            app.data.info.Cr.(theRealization)(ap.nu,theExperiment) = array2table(nan(1));
                        end
                        indx_meas = (1:multisineRate/app.Fs_Y:length(theExcitation));
                        app.data.u_measure.(theRealization).(theExperiment) = squeeze(theExcitation(:,j,indx_meas));
                        %app.data.r.(theRealization).(theExperiment) = squeeze(theReference(:,j,:)); 
                        %update the progress bar
                        count = 1 + count;
                        updateProgressBar(app, count/nsteps)
                    end % j loop
                
                end % i loop
                
                plotFFT(app);
                plotMultisine(app);
                updateProgressBar(app, 1);
                title(app.progressBarAxes,'Finished','Color',[1 1 1]);
            end
            % remove the dummy field we needed to first init the struct
            if isfield(app.data,'dummyField')
                app.data = rmfield(app.data,'dummyField');
            end
            
        end

        % Value changed function: XCheckBox
        function XCheckBoxValueChanged(app, event)
            updateActiveCheckboxes(app);
            %value = app.XCheckBox.Value;
        end

        % Value changed function: YCheckBox
        function YCheckBoxValueChanged(app, event)
            updateActiveCheckboxes(app);
            %value = app.YCheckBox.Value;
        end

        % Value changed function: ZCheckBox
        function ZCheckBoxValueChanged(app, event)
            updateActiveCheckboxes(app);
            %value = app.ZCheckBox.Value;
        end

        % Value changed function: RollCheckBox
        function RollCheckBoxValueChanged(app, event)
            updateActiveCheckboxes(app);
            %value = app.RollCheckBox.Value;
        end

        % Value changed function: PitchCheckBox
        function PitchCheckBoxValueChanged(app, event)
            updateActiveCheckboxes(app);
            %value = app.PitchCheckBox.Value;
        end

        % Value changed function: YawCheckBox
        function YawCheckBoxValueChanged(app, event)
            updateActiveCheckboxes(app);
            %value = app.YawCheckBox.Value;
        end

        % Value changed function: SelectAllCheckBox
        function SelectAllCheckBoxValueChanged(app, event)
            value = app.SelectAllCheckBox.Value;
            % set all check boxes to checked
            app.XCheckBox.Value = value;
            app.YCheckBox.Value = value;
            app.ZCheckBox.Value = value;
            app.RollCheckBox.Value = value;
            app.PitchCheckBox.Value = value;
            app.YawCheckBox.Value = value;
            % update the active checkboxes
            updateActiveCheckboxes(app);
        end

        % Value changing function: ExprimentalFrequencyBandHzSlider
        function ExprimentalFrequencyBandHzSliderValueChanging(app, event)
            sliderValue = event.Value;
            % enforce minimum frequency range
            if sliderValue(2) - sliderValue(1) < app.minFreqRange
                sliderValue(2) = sliderValue(1) + app.minFreqRange;
            end
            % enforce min frequency limits
            if sliderValue(1) < app.MinimumFrequencyHzSpinner.Limits(1)
                % Hexapod cannot be run below 0.25
                sliderValue(1) = app.MinimumFrequencyHzSpinner.Limits(1);
            elseif sliderValue(1) > app.MinimumFrequencyHzSpinner.Limits(2)
                sliderValue(1) = app.MinimumFrequencyHzSpinner.Limits(2);
            end
            % enforce max frequency limits
            if sliderValue(2) < app.MaximumFrequencyHzSpinner.Limits(1)
                % Hexapod cannot be run below 0.25
                sliderValue(2) = app.MaximumFrequencyHzSpinner.Limits(1);
            elseif sliderValue(2) > app.MaximumFrequencyHzSpinner.Limits(2)
                sliderValue(2) = app.MaximumFrequencyHzSpinner.Limits(2);
            end
            app.MinimumFrequencyHzSpinner.Value = sliderValue(1);
            app.MinFreq = sliderValue(1);
            app.MaximumFrequencyHzSpinner.Value = sliderValue(2);
            app.MaxFreq = sliderValue(2);
            sampleTime(app);
        end

        % Value changing function: MinimumFrequencyHzSpinner
        function MinimumFrequencyHzSpinnerValueChanging(app, event)
            minFreq = event.Value;
            maxFreq = app.MaximumFrequencyHzSpinner.Value;
            if (maxFreq - minFreq) < app.minFreqRange
                errorMessage = sprintf('Minimum range between frequencies must be at least %.2f.',app.minFreqRange);
                uialert(app.UIFigure, errorMessage, 'Range Error','Icon','warning');
                maxFreq = minFreq + app.minFreqRange;
                app.MaximumFrequencyHzSpinner.Value = maxFreq;
                app.MaxFreq = maxFreq;
            end
            app.MinFreq = minFreq;
            sampleTime(app);
            app.ExprimentalFrequencyBandHzSlider.Value = [app.MinFreq, app.MaxFreq];
        end

        % Value changing function: MaximumFrequencyHzSpinner
        function MaximumFrequencyHzSpinnerValueChanging(app, event)
            maxFreq = event.Value;
            minFreq = app.MinimumFrequencyHzSpinner.Value;
            if (maxFreq - minFreq) < app.minFreqRange
                errorMessage = sprintf('Minimum range between frequencies must be at least %.2f.',app.minFreqRange);
                uialert(app.UIFigure, errorMessage, 'Range Error','Icon','warning');
                minFreq = maxFreq - app.minFreqRange;
                if minFreq < app.MinimumFrequencyHzSpinner.Limits(1)
                    minFreq = app.MinimumFrequencyHzSpinner.Limits(1);
                    maxFreq = minFreq + app.minFreqRange;
                end
                app.MinimumFrequencyHzSpinner.Value = minFreq;
                app.MinFreq = minFreq;
                sampleTime(app)
            end
            app.MaxFreq = maxFreq;
            app.ExprimentalFrequencyBandHzSlider.Value = [app.MinFreq,app.MaxFreq];
        end

        % Value changed function: NumberofPeriodsPSpinner
        function NumberofPeriodsPSpinnerValueChanged(app, event)
            if mod(app.NumberofPeriodsPSpinner.Value, 1) ~= 0
                uialert(app.UIFigure,'Number of Periods must be an integer',...
                    'Input Error','Icon','warning');
                P = round(app.NumberofPeriodsPSpinner.Value); % must be an int
                app.NumberofPeriodsPSpinner.Value = P;
            end
            app.Periods = app.NumberofPeriodsPSpinner.Value;
            calcExperimentTime(app);
        end

        % Value changed function: NumberofRealiationsMSpinner
        function NumberofRealiationsMSpinnerValueChanged(app, event)
            if mod(app.NumberofRealiationsMSpinner.Value, 1) ~= 0
                uialert(app.UIFigure,'Number of Realizations must be an integer',...
                    'Input Error','Icon','warning');
                M = round(app.NumberofRealiationsMSpinner.Value); % must be int
                app.NumberofRealiationsMSpinner.Value = M;
            end
            app.Realizations = app.NumberofRealiationsMSpinner.Value;
            calcExperimentTime(app);
        end

        % Value changed function: ScaleFactorSpinner
        function ScaleFactorSpinnerValueChanged(app, event)
            if mod(app.ScaleFactorSpinner.Value, 1) ~= 0
                uialert(app.UIFigure,'Scale Factor must be an integer',...
                    'Input Error','Icon','warning');
                SF = round(app.ScaleFactorSpinner.Value);
                app.ScaleFactorSpinner.Value = SF;
            end
            app.scaleFactor = app.ScaleFactorSpinner.Value;
            sampleTime(app);
        end

        % Button pushed function: ChooseFolderButton
        function ChooseFolderButtonPushed(app, event)
            % bring up a UIdir to choose the folder to save the files too
            if ispc
                defaultPath = fullfile(getenv('USERPROFILE'),'Desktop');
            else
                defaultPath = fullfile(getenv('Home'),'Desktop');
            end
            
            dname = uigetdir(defaultPath, 'Select Folder to Export .Mat data to');
            if dname ~= 0
                app.ChooseFolderEditField.Value = dname;
                app.Dir = dname;
                app.isDir = true;
            else
                uialert(app.UIFigure,'Directory Window Closed Unexpectedly',...
                        'Input Error');
            end
        end

        % Button pushed function: ExportData
        function ExportDataButtonPushed(app, event)
            % check if the directory for saving has been specified, if not 
            % trigger the ChooseFolder action
            if ~app.isDir
                fig = uifigure;
                selection = uiconfirm(fig,"No Directory Specified!",...
                    "Invalid Directory","Icon","error");
                switch selection
                    case 'OK'
                        delete(fig);
                        pause(1); % wait a second before opening the window
                        ChooseFolderButtonPushed(app,event);
                    case 'Cancel'
                        delete(fig);
                        return % exit the sequence
                end
            else
                % check that app.data.u and app.data.r exist
                if isfield(app.data,'u_out') && isfield(app.data,'r')
                    % add info to data
                    app.data.info.Periods = app.Periods;
                    app.data.info.Realizations = app.Realizations;
                    app.data.info.ScaleFactor = app.scaleFactor;
                    DOFNames = {'X','Y','Z','P','Q','R'};
                    app.data.info.Inputs = DOFNames(app.activeInputs);
                    % create folder name
                    dateTimeStr = datetime('now','Timezone','local','Format','yyyMMdd_HHmms');
                    DOFString = strjoin(DOFNames(app.activeInputs),'');
                    folderName = sprintf('Data_%s-DOF_%s',DOFString,dateTimeStr);
                    fullPath = fullfile(app.Dir,folderName);
                    
                    MultiSine_Excitation = app.data;
                    
                    try
                        mkdir(fullPath); % make a new dir
                        fileName = sprintf('U_Data_%s-DOF_%s.mat',DOFString,dateTimeStr);
                        fullFilePath = fullfile(fullPath,fileName);
                        save(fullFilePath,'MultiSine_Excitation');
                        Mfields = fieldnames(app.data.u_out);
                        for i = 1:app.Realizations
                            Efields = fieldnames(app.data.u_out.(Mfields{i}));
                            for j = 1:app.nu
                                pose_t_temp = app.data.u_out.(Mfields{i}).(Efields{j});
                                N_t = size(pose_t_temp,2);
                                t_temp = linspace(0,N_t*(1/app.Fs_U),N_t);

                                app.hex_path.pose_t = pose_t_temp;
                                app.hex_path.T = t_temp;
                                
                                fnameout = sprintf('U_R%d_E%d_%s.mat',i,j,dateTimeStr);
                                hex_path = app.hex_path; %#ok<ADPROPLC>
                                fullFilePath = fullfile(fullPath,fnameout);
                                save(fullFilePath,"hex_path");
                            end
                        end
                        message = sprintf('Data successfully exported as %s',fileName);
                        uialert(app.UIFigure,message,"Success",'Icon','success');
                    catch e
                        uialert(app.UIFigure, sprintf('Failed to export data: %s', e.message),'Error');
                    end
                else
                    uialert(app.UIFigure,'Multisine Data not yet created!',...
                        'Input Error');
                end
            end
        end

        % Value changing function: MaxAmplitudeSlider
        function MaxAmplitudeSliderValueChanging(app, event)
            sliderValue = event.Value;
            % enforce spinner limit
            if sliderValue < app.MaxAmplitudeSpinner.Limits(1)
                sliderValue = app.MaxAmplitudeSpinner.Limits(1);
            elseif sliderValue > app.MaxAmplitudeSpinner.Limits(2)
                sliderValue = app.MaxAmplitudeSpinner.Limits(2);
            end
            SetMaxAmplitude(app,sliderValue);
            app.MaxAmplitudeSpinner.Value = sliderValue;
            
        end

        % Value changing function: MaxAmplitudeSpinner
        function MaxAmplitudeSpinnerValueChanging(app, event)
            MaxAmp = event.Value;
            % enforce slider limits
            if MaxAmp < app.MaxAmplitudeSlider.Limits(1)
                MaxAmp = app.MaxAmplitudeSlider.Limits(1);
            elseif MaxAmp > app.MaxAmplitudeSlider.Limits(2)
                MaxAmp = app.MaxAmplitudeSlider.Limits(2);
            end
            SetMaxAmplitude(app,MaxAmp);
            % always do the following
            app.MaxAmplitudeSlider.Value = MaxAmp;
          
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.LeftPanel = uipanel(app.UIFigure);
            app.progressBarAxes = uiaxes(app.LeftPanel);
            app.progressBarAxes.XTick = [];
            app.progressBarAxes.XTickLabel = '';
            app.progressBarAxes.YTick = [];
            app.progressBarAxes.YTickLabel = '';
            app.progressBarAxes.ZTick = [0 0.5 1];
            app.progressBarAxes.ZTickLabel = '';
            app.XCheckBox = uicheckbox(app.LeftPanel);
            app.XCheckBox.ValueChangedFcn = createCallbackFcn(app, @XCheckBoxValueChanged, true);
            app.XCheckBox.Tooltip = {'Select the degree(s) of freedom about which to excite the hexapod'};
            app.XCheckBox.Text = 'X';
            app.YCheckBox = uicheckbox(app.LeftPanel);
            app.YCheckBox.ValueChangedFcn = createCallbackFcn(app, @YCheckBoxValueChanged, true);
            app.YCheckBox.Tooltip = {'Select the degree(s) of freedom about which to excite the hexapod'};
            app.YCheckBox.Text = 'Y';
            app.ZCheckBox = uicheckbox(app.LeftPanel);
            app.ZCheckBox.ValueChangedFcn = createCallbackFcn(app, @ZCheckBoxValueChanged, true);
            app.ZCheckBox.Tooltip = {'Select the degree(s) of freedom about which to excite the hexapod'};
            app.ZCheckBox.Text = 'Z';
            app.RollCheckBox = uicheckbox(app.LeftPanel);
            app.RollCheckBox.ValueChangedFcn = createCallbackFcn(app, @RollCheckBoxValueChanged, true);
            app.RollCheckBox.Tooltip = {'Select the degree(s) of freedom about which to excite the hexapod'};
            app.RollCheckBox.Text = 'Roll';
            app.PitchCheckBox = uicheckbox(app.LeftPanel);
            app.PitchCheckBox.ValueChangedFcn = createCallbackFcn(app, @PitchCheckBoxValueChanged, true);
            app.PitchCheckBox.Tooltip = {'Select the degree(s) of freedom about which to excite the hexapod'};
            app.PitchCheckBox.Text = 'Pitch';
            app.YawCheckBox = uicheckbox(app.LeftPanel);
            app.YawCheckBox.ValueChangedFcn = createCallbackFcn(app, @YawCheckBoxValueChanged, true);
            app.YawCheckBox.Tooltip = {'Select the degree(s) of freedom about which to excite the hexapod'};
            app.YawCheckBox.Text = 'Yaw';
            app.SelectAllCheckBox = uicheckbox(app.LeftPanel);
            app.SelectAllCheckBox.ValueChangedFcn = createCallbackFcn(app, @SelectAllCheckBoxValueChanged, true);
            app.SelectAllCheckBox.Tooltip = {'Select the degree(s) of freedom about which to excite the hexapod'};
            app.SelectAllCheckBox.Text = 'Select All';
            app.NumberofInputsNuLabel = uilabel(app.LeftPanel);
            app.NumberofInputsNuLabel.Text = 'Number of Inputs, Nu';
            app.NumberofInputsNuEditField = uieditfield(app.LeftPanel, 'numeric');
            app.NumberofInputsNuEditField.Limits = [0 6];
            app.NumberofInputsNuEditField.Editable = 'off';
            app.NumberofInputsNuEditField.Tooltip = {'Displays the number of active degrees of freedom'};
            app.SamplingRateFsEditFieldLabel = uilabel(app.LeftPanel);
            app.SamplingRateFsEditFieldLabel.Text = 'Sampling Rate, Fs';
            app.SamplingRateFsEditField = uieditfield(app.LeftPanel, 'numeric');
            app.SamplingRateFsEditField.Limits = [0 256];
            app.SamplingRateFsEditField.Editable = 'off';
            app.SamplingRateFsEditField.Tooltip = {'Hexapod motion execution rate'};
            app.SamplingRateFsEditField.Value = 256;
            app.SampletimeLabel = uilabel(app.LeftPanel);
            app.SampletimeLabel.Text = 'Sample time for one period, Ts ';
            app.Sampletime = uieditfield(app.LeftPanel, 'numeric');
            app.Sampletime.Limits = [0.2 300];
            app.Sampletime.Editable = 'off';
            app.Sampletime.Tooltip = {'The sampling time of one period in seconds. This value is altered by the lowest frequency in the experimental frequency band.'};
            app.Sampletime.Value = 10;
            app.ExprimentalFrequencyBandHzSliderLabel = uilabel(app.LeftPanel);
            app.ExprimentalFrequencyBandHzSliderLabel.Text = {'Exprimental '; 'Frequency Band (Hz) '};
            app.ExprimentalFrequencyBandHzSlider = uislider(app.LeftPanel, 'range');
            app.ExprimentalFrequencyBandHzSlider.Limits = [0 5];
            app.ExprimentalFrequencyBandHzSlider.ValueChangingFcn = createCallbackFcn(app, @ExprimentalFrequencyBandHzSliderValueChanging, true);
            app.ExprimentalFrequencyBandHzSlider.Tooltip = {'Select the range of frequencies, in Hz, to be excited during the test'};
            app.ExprimentalFrequencyBandHzSlider.Value = [0.25 5];
            app.MinimumFrequencyLabel = uilabel(app.LeftPanel);
            app.MinimumFrequencyLabel.Text = {'Minimum '; 'Frequency (Hz)'};
            app.MinimumFrequencyHzSpinner = uispinner(app.LeftPanel);
            app.MinimumFrequencyHzSpinner.ValueChangingFcn = createCallbackFcn(app, @MinimumFrequencyHzSpinnerValueChanging, true);
            app.MinimumFrequencyHzSpinner.Limits = [0.25 4.9];
            app.MinimumFrequencyHzSpinner.Tooltip = {'Select the minimum frequency, in Hz, for testing. Note this value alters the one period sampling time.'};
            app.MinimumFrequencyHzSpinner.Value = 0.25;
            app.GenerateMultisineButton = uibutton(app.LeftPanel, 'push');
            app.GenerateMultisineButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateMultisineButtonPushed, true);
            app.GenerateMultisineButton.Tooltip = {'Clicking this button will generate the multisine signal with the above user determined attributes. You may regenerate this signal as many times as you wish to tune your result.'};
            app.GenerateMultisineButton.Text = 'Generate Multisine';
            app.MaximumFrequencyHzSpinnerLabel = uilabel(app.LeftPanel);
            app.MaximumFrequencyHzSpinnerLabel.Text = {'Maximum'; 'Frequency (Hz)'};
            app.MaximumFrequencyHzSpinner = uispinner(app.LeftPanel);
            app.MaximumFrequencyHzSpinner.ValueChangingFcn = createCallbackFcn(app, @MaximumFrequencyHzSpinnerValueChanging, true);
            app.MaximumFrequencyHzSpinner.Limits = [0.35 5];
            app.MaximumFrequencyHzSpinner.Tooltip = {'Select the maximum frequency, in Hz, for testing.'};
            app.MaximumFrequencyHzSpinner.Value = 5;
            app.NumberofPeriodsPSpinnerLabel = uilabel(app.LeftPanel);
            app.NumberofPeriodsPSpinnerLabel.Text = 'Number o f Periods, P';
            app.NumberofPeriodsPSpinner = uispinner(app.LeftPanel);
            app.NumberofPeriodsPSpinner.Limits = [2 60];
            app.NumberofPeriodsPSpinner.ValueChangedFcn = createCallbackFcn(app, @NumberofPeriodsPSpinnerValueChanged, true);
            app.NumberofPeriodsPSpinner.Tooltip = {'The number of repeated periods of the base generated signal to use for one experiment. More periods gives a better estimate of the FRM for a given experiment.'};
            app.NumberofPeriodsPSpinner.Value = 6;
            app.NumberofRealiationsMSpinnerLabel = uilabel(app.LeftPanel);
            app.NumberofRealiationsMSpinnerLabel.Text = 'Number of Realiations, M';
            app.NumberofRealiationsMSpinner = uispinner(app.LeftPanel);
            app.NumberofRealiationsMSpinner.Limits = [2 10];
            app.NumberofRealiationsMSpinner.ValueChangedFcn = createCallbackFcn(app, @NumberofRealiationsMSpinnerValueChanged, true);
            app.NumberofRealiationsMSpinner.Tooltip = {'The number of unique realizations of the phase distribution for the multisine signal. Generally ideal to keep this number as low as possible. '};
            app.NumberofRealiationsMSpinner.Value = 10;
            app.ScaleFactorSpinnerLabel = uilabel(app.LeftPanel);
            app.ScaleFactorSpinnerLabel.Text = 'Scale  Factor';
            app.ScaleFactorSpinner = uispinner(app.LeftPanel);
            app.ScaleFactorSpinner.Limits = [1 32];
            app.ScaleFactorSpinner.ValueChangedFcn = createCallbackFcn(app, @ScaleFactorSpinnerValueChanged, true);
            app.ScaleFactorSpinner.Tooltip = {'Adjusting the scale factor increases the sampling time for one period. Adjust this value after setting the minimum frequency if the experiment time or total testing time are below recommened'};
            app.ScaleFactorSpinner.Value = 1;
            app.IIHRHexapodMultisineGeneratorLabel = uilabel(app.LeftPanel);
            app.IIHRHexapodMultisineGeneratorLabel.Text = 'IIHR Hexapod Multisine Generator';
            app.MaxAmplitudeLabel = uilabel(app.LeftPanel);
            app.MaxAmplitudeLabel.Text = {'Max '; 'Amplitude'; '(% of Full)'};
            app.MaxAmplitudeSlider = uislider(app.LeftPanel);
            app.MaxAmplitudeSlider.Limits = [1 8];
            app.MaxAmplitudeSlider.MajorTicks = [1 2 3 4 5 6 7 8];
            app.MaxAmplitudeSlider.ValueChangingFcn = createCallbackFcn(app, @MaxAmplitudeSliderValueChanging, true);
            app.MaxAmplitudeSlider.Value = 1;
            app.MaxAmplitudeSpinnerLabel = uilabel(app.LeftPanel);
            app.MaxAmplitudeSpinnerLabel.Text = {'Max '; 'Amplitude'; '(% of Full)'};
            app.MaxAmplitudeSpinner = uispinner(app.LeftPanel);
            app.MaxAmplitudeSpinner.Step = 0.5;
            app.MaxAmplitudeSpinner.ValueChangingFcn = createCallbackFcn(app, @MaxAmplitudeSpinnerValueChanging, true);
            app.MaxAmplitudeSpinner.Limits = [1 8];
            app.MaxAmplitudeSpinner.Value = 1;
            app.RightPanel = uipanel(app.UIFigure);
            app.fftAxes = uiaxes(app.RightPanel);
            title(app.fftAxes, 'FFT of Excitation Signals')
            xlabel(app.fftAxes, 'Frequency (Hz)')
            ylabel(app.fftAxes, 'Magnitude (db)')
            zlabel(app.fftAxes, 'Z')
            app.fftAxes.XLim = [0 6];
            app.ExampleSignal = uiaxes(app.RightPanel);
            title(app.ExampleSignal, 'Multisine Signal')
            xlabel(app.ExampleSignal, 'Time')
            ylabel(app.ExampleSignal, 'Displacement (m or deg)')
            app.ExportData = uibutton(app.RightPanel, 'push');
            app.ExportData.ButtonPushedFcn = createCallbackFcn(app, @ExportDataButtonPushed, true);
            app.ExportData.Tooltip = {'Exports the generated multisine signal as a .Mat file. The file is identifiable by the number of inputs, the number of periods, and the realizations as well as the date time when it was created.'};
            app.ExportData.Text = 'Export .Mat';
            app.ChooseFolderEditField = uieditfield(app.RightPanel, 'text');
            app.ChooseFolderEditField.Editable = 'off';
            app.ChooseFolderEditField.Tooltip = {'The directory where you wish to export your generated multisine signal.'};
            app.ChooseFolderButton = uibutton(app.RightPanel, 'push');
            app.ChooseFolderButton.ButtonPushedFcn = createCallbackFcn(app, @ChooseFolderButtonPushed, true);
            app.ChooseFolderButton.Tooltip = {'The directory where you wish to export your generated multisine signal.'};
            app.ChooseFolderButton.Text = 'Choose Folder';
            app.TotalTestingTimeEditFieldLabel = uilabel(app.RightPanel);
            app.TotalTestingTimeEditFieldLabel.Text = 'Total Testing Time';
            app.TotalTestingTime = uieditfield(app.RightPanel, 'numeric');
            app.TotalTestingTime.Editable = 'off';
            app.TotalTestingTime.Tooltip = {'The time to complete all experiments to obtain an estimate of the FRM. It is the product of the time for each experiment, the number of realizations, and the number of inputs.'};
            app.TimeforEachExperimentEditFieldLabel = uilabel(app.RightPanel);
            app.TimeforEachExperimentEditFieldLabel.Text = 'Time for Each Experiment';
            app.TimeforEachExperiment = uieditfield(app.RightPanel, 'numeric');
            app.TimeforEachExperiment.Editable = 'off';
            app.TimeforEachExperiment.Tooltip = {'The time to complete one experiment. Product of the scale factor, the sample time and the number of periods.'};
            app.RecommendationNote = uilabel(app.RightPanel);
            app.RecommendationNote.Text = 'Recommend: ''Total Testing Time'' of nu * 600 sec (e.g. for nu = 6, total testing time = 3600 sec';
            app.V072Label = uilabel(app.RightPanel);
            app.V072Label.Text = 'V0.7.2';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Hexapod_Generate_MultiSine_Excitation

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end