classdef Point_to_Point < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        LoadPointSequenceButton       matlab.ui.control.Button
        SavePointSequenceButton       matlab.ui.control.Button
        DatumTypeButtonGroup          matlab.ui.container.ButtonGroup
        RelativeButton                matlab.ui.control.RadioButton
        AbsoluteButton                matlab.ui.control.RadioButton
        AddsegmentsdescribingthemotionoftheendeffectorLabel_2  matlab.ui.control.Label
        MotionProfileTypeButtonGroup  matlab.ui.container.ButtonGroup
        SmoothedButton                matlab.ui.control.RadioButton
        TrapezoidalButton             matlab.ui.control.RadioButton
        AddsegmentsdescribingthemotionoftheendeffectorLabel  matlab.ui.control.Label
        CancelButton                  matlab.ui.control.Button
        DoneButton                    matlab.ui.control.Button
        AddSegmentButton              matlab.ui.control.Button
        UITable                       matlab.ui.control.Table
    end


    properties (Access = public)
        Seg_Table=table(); % Motion segment table
        MotionType=0;
    end

    properties (Access = private)
        CallingApp % Description
        CurrentPoseAbs=0;
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, caller, hex_obj)
            app.CallingApp=caller;
            CurrentPose=[0;0;0;0;0;0];
            app.CurrentPoseAbs=hex_obj.pose;
            %             CurrentPose(4:6)=rad2deg(CurrentPose);
            TableRow=array2table([0 CurrentPose'],'VariableNames',{'Time','X','Y','Z','Rx','Ry','Rz'});
            app.UITable.Data=TableRow;
        end

        % Button pushed function: AddSegmentButton
        function AddSegmentButtonPushed(app, event)
            OldTable=app.UITable.Data;
            N_rows=size(OldTable,1);
            NewTable=[OldTable;OldTable(end,:)];
            app.Seg_Table=NewTable;
            app.UITable.Data=NewTable;
        end

        % Cell edit callback: UITable
        function UpdateTable(app, event)
            indices = event.Indices;
            newData = event.NewData;
            app.Seg_Table=app.UITable.Data;
            selectedButton = app.DatumTypeButtonGroup.SelectedObject

            switch selectedButton.Text
                case 'Relative';
                    FirstRow=array2table([0 0 0 0 0 0 0]);
                case 'Absolute'
                    CurrentPose=app.CurrentPoseAbs;
                    CurrentPose(4:6)=rad2deg(CurrentPose(4:6));
                    FirstRow=array2table([0 CurrentPose']);
            end
            Seg_Table=app.Seg_Table;
            Seg_Table(1,:)=FirstRow;
            app.UITable.Data=Seg_Table;
            Seg_Table

        end

        % Button pushed function: DoneButton
        function DoneButtonPushed(app, event)
            Segmenttypebutton=app.MotionProfileTypeButtonGroup.SelectedObject;
            switch Segmenttypebutton.Text
                case 'Trapezoidal'
                    Segmenttype=0;
                case 'Smoothed'
                    Segmenttype=1;
            end

            DatumButton = app.DatumTypeButtonGroup.SelectedObject
            switch DatumButton.Text
                case 'Relative';
                    DatumType=0;
                case 'Absolute';
                    DatumType=1;
            end

            if size(app.Seg_Table,1)>1
Seg_Table=app.Seg_Table;
                timecolumn=Seg_Table.(1);
                if and(max(timecolumn)>0,timecolumn(1)==0)

                    Grab_Point_to_PointData(app.CallingApp,app.Seg_Table,Segmenttype,DatumType);
                    delete(app)
                else
                    msgbox('Invalid Times')
                end
            else
                msgbox('Must have more than one row')
            end

        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            delete(app);
        end

        % Selection changed function: DatumTypeButtonGroup
        function DatumTypeButtonGroupSelectionChanged(app, event)
            selectedButton = app.DatumTypeButtonGroup.SelectedObject

            switch selectedButton.Text
                case 'Relative';
                    FirstRow=array2table([0 0 0 0 0 0 0]);
                case 'Absolute'
                    CurrentPose=app.CurrentPoseAbs;
                    CurrentPose(4:6)=rad2deg(CurrentPose(4:6));
                    FirstRow=array2table([0 CurrentPose']);
            end
            Seg_Table=app.Seg_Table;
            Seg_Table(1,:)=FirstRow;
            app.UITable.Data=Seg_Table;

        end

        % Button pushed function: SavePointSequenceButton
        function SavePointSequenceButtonPushed(app, event)
             Seg_Table=app.Seg_Table;
             [fname fpath]=uiputfile('*.xlsx','Choose file and location to save point sequence');
             writetable(Seg_Table,[fpath fname]);
        end

        % Button pushed function: LoadPointSequenceButton
        function LoadPointSequenceButtonPushed(app, event)
            [fname fpath]=uigetfile('*.xlsx','Choose file to load point sequence');
            Seg_Table=readtable([fpath fname]);
            app.UITable.Data=Seg_Table;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Time'; 'X'; 'Y'; 'Z'; 'Rx'; 'Ry'; 'Rz'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = true;
            app.UITable.CellEditCallback = createCallbackFcn(app, @UpdateTable, true);
            app.AddSegmentButton = uibutton(app.UIFigure, 'push');
            app.AddSegmentButton.ButtonPushedFcn = createCallbackFcn(app, @AddSegmentButtonPushed, true);
            app.AddSegmentButton.Text = {'Add '; 'Segment'};
            app.DoneButton = uibutton(app.UIFigure, 'push');
            app.DoneButton.ButtonPushedFcn = createCallbackFcn(app, @DoneButtonPushed, true);
            app.DoneButton.Text = 'Done';
            app.CancelButton = uibutton(app.UIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.AddsegmentsdescribingthemotionoftheendeffectorLabel = uilabel(app.UIFigure);
            app.AddsegmentsdescribingthemotionoftheendeffectorLabel.Text = {'Add segments describing the motion of the end effector. Click ''Add Segment'' to include '; 'each additional point.'};
            app.MotionProfileTypeButtonGroup = uibuttongroup(app.UIFigure);
            app.MotionProfileTypeButtonGroup.Title = 'Motion Profile Type';
            app.TrapezoidalButton = uiradiobutton(app.MotionProfileTypeButtonGroup);
            app.TrapezoidalButton.Text = 'Trapezoidal';
            app.SmoothedButton = uiradiobutton(app.MotionProfileTypeButtonGroup);
            app.SmoothedButton.Text = 'Smoothed';
            app.SmoothedButton.Value = true;
            app.AddsegmentsdescribingthemotionoftheendeffectorLabel_2 = uilabel(app.UIFigure);
            app.AddsegmentsdescribingthemotionoftheendeffectorLabel_2.Text = {'Trapezoidal motion will use straight-line segments to connect each point. '; 'Smoothed motion will use a scaled hyperbolic tangent to produce smooth "S" motion segments.'};
            app.DatumTypeButtonGroup = uibuttongroup(app.UIFigure);
            app.DatumTypeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @DatumTypeButtonGroupSelectionChanged, true);
            app.DatumTypeButtonGroup.Title = 'Datum Type';
            app.AbsoluteButton = uiradiobutton(app.DatumTypeButtonGroup);
            app.AbsoluteButton.Text = 'Absolute';
            app.RelativeButton = uiradiobutton(app.DatumTypeButtonGroup);
            app.RelativeButton.Text = 'Relative';
            app.RelativeButton.Value = true;
            app.SavePointSequenceButton = uibutton(app.UIFigure, 'push');
            app.SavePointSequenceButton.ButtonPushedFcn = createCallbackFcn(app, @SavePointSequenceButtonPushed, true);
            app.SavePointSequenceButton.Text = 'Save Point Sequence';
            app.LoadPointSequenceButton = uibutton(app.UIFigure, 'push');
            app.LoadPointSequenceButton.ButtonPushedFcn = createCallbackFcn(app, @LoadPointSequenceButtonPushed, true);
            app.LoadPointSequenceButton.Text = 'Load Point Sequence';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Point_to_Point(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end