classdef SineSeries < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        LoadPointSequenceButton       matlab.ui.control.Button
        SavePointSequenceButton       matlab.ui.control.Button
        MotionDurationEditField       matlab.ui.control.NumericEditField
        MotionDurationEditFieldLabel  matlab.ui.control.Label
        AddsegmentsdescribingthemotionoftheendeffectorLabel_2  matlab.ui.control.Label
        AddsegmentsdescribingthemotionoftheendeffectorLabel  matlab.ui.control.Label
        CancelButton                  matlab.ui.control.Button
        DoneButton                    matlab.ui.control.Button
        AddComponentButton            matlab.ui.control.Button
        UITable                       matlab.ui.control.Table
    end

    
    properties (Access = public)
        Seg_Table=table(); % Motion segment table
        MotionType=0; 
    end
    
    properties (Access = private)
        CallingApp % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, caller, hex_obj)
            app.CallingApp=caller;
            CurrentPose=hex_obj.pose;
            TableRow=array2table([0 0 0 0 0 0 0 0],'VariableNames',{'Frequency [hz]','X [m]','Y [m]','Z [m]','Rx [deg]','Ry [deg]','Rz [deg]','Phase [deg]'});
            app.UITable.Data=TableRow;
        end

        % Button pushed function: AddComponentButton
        function AddComponentButtonPushed(app, event)
            OldTable=app.UITable.Data;
            N_rows=size(OldTable,1);
            NewTable=[OldTable;OldTable(end,:)];
            app.Seg_Table=NewTable;
            app.UITable.Data=NewTable;
        end

        % Cell edit callback: UITable
        function UpdateTable(app, event)
            indices = event.Indices;
            newData = event.NewData;
            app.Seg_Table=app.UITable.Data;
        end

        % Button pushed function: DoneButton
        function DoneButtonPushed(app, event)
%             DatumTypeButton=app.MotionDatumButtonGroup.SelectedObject;
%         switch DatumTypeButton.Text
%             case 'Home Position'
%                 DatumType=0;
%             case 'Current Position'
%                 DatumType=1;
%         end

        Grab_SineSeriesData(app.CallingApp,app.Seg_Table,app.MotionDurationEditField.Value);
        delete(app)

        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            delete(app);
        end

        % Button pushed function: SavePointSequenceButton
        function SavePointSequenceButtonPushed(app, event)
                        Seg_Table=app.Seg_Table;
             [fname fpath]=uiputfile('*.xlsx','Choose file and location to save sine series');
             writetable(Seg_Table,[fpath fname]);
        
        end

        % Button pushed function: LoadPointSequenceButton
        function LoadPointSequenceButtonPushed(app, event)
           [fname fpath]=uigetfile('*.xlsx','Choose file to load sine series');
            Seg_Table=readtable([fpath fname]);
            app.UITable.Data=Seg_Table;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Frequency [hz]'; 'X [m]'; 'Y [m]'; 'Z [m]'; 'Rx [deg]'; 'Ry [deg]'; 'Rz [deg]'; 'Phase [deg]'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = true;
            app.UITable.CellEditCallback = createCallbackFcn(app, @UpdateTable, true);
            app.AddComponentButton = uibutton(app.UIFigure, 'push');
            app.AddComponentButton.ButtonPushedFcn = createCallbackFcn(app, @AddComponentButtonPushed, true);
            app.AddComponentButton.Text = 'Add Component';
            app.DoneButton = uibutton(app.UIFigure, 'push');
            app.DoneButton.ButtonPushedFcn = createCallbackFcn(app, @DoneButtonPushed, true);
            app.DoneButton.Text = 'Done';
            app.CancelButton = uibutton(app.UIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.AddsegmentsdescribingthemotionoftheendeffectorLabel = uilabel(app.UIFigure);
            app.AddsegmentsdescribingthemotionoftheendeffectorLabel.Text = 'Add components of sinusoidal motion. Click "add component" to append another row.';
            app.AddsegmentsdescribingthemotionoftheendeffectorLabel_2 = uilabel(app.UIFigure);
            app.AddsegmentsdescribingthemotionoftheendeffectorLabel_2.Text = 'All motions are computed about the current position.';
            app.MotionDurationEditFieldLabel = uilabel(app.UIFigure);
            app.MotionDurationEditFieldLabel.Text = 'Motion Duration';
            app.MotionDurationEditField = uieditfield(app.UIFigure, 'numeric');
            app.SavePointSequenceButton = uibutton(app.UIFigure, 'push');
            app.SavePointSequenceButton.ButtonPushedFcn = createCallbackFcn(app, @SavePointSequenceButtonPushed, true);
            app.SavePointSequenceButton.Text = 'Save Point Sequence';
            app.LoadPointSequenceButton = uibutton(app.UIFigure, 'push');
            app.LoadPointSequenceButton.ButtonPushedFcn = createCallbackFcn(app, @LoadPointSequenceButtonPushed, true);
            app.LoadPointSequenceButton.Text = 'Load Point Sequence';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SineSeries(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end